classdef ElecLandmarkAlignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        PlotAlgnAreaInFig         matlab.ui.control.Button
        RePlotButton              matlab.ui.control.Button
        LFPRightClip              matlab.ui.control.Button
        LFPLeftClip               matlab.ui.control.Button
        SaveResultButton          matlab.ui.control.Button
        AlignmentpreviewerButton  matlab.ui.control.Button
        SelectPathButton          matlab.ui.control.Button
        SessPath                  matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        LFPpower                  matlab.ui.control.UIAxes
        ChannelArea_Ax            matlab.ui.control.UIAxes
        LFP_Ax                    matlab.ui.control.UIAxes
        Amplitude_Ax              matlab.ui.control.UIAxes
        SpikeRate_Ax              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ksRawfilePath = ''; % ks raw data path
        ksOutfilePath = ''; % ks output data path
        IsKsPathChanged = 0;
        ksSortingType = 'ks2_5';
        
        lfpDefaultTimeLen = 30; % 20 seconds data from the start
        lfpDefaultClip = 2; % seconds block used for lfp amplitude plot, that is the 2nd seconds after start
        lfpUsedClip = 2;
        
        % NP 1.0 default parameters
        lfpfs = 2500; % default lfp sample rate
        apfs = 30000; % default ap sample rate
        NumChn = 385;
        NumSamples = [];
        
        
        % loaded datas
        lfpData = [];
        sp
        spTimes
        spAmps
        spDepths
        spSites
        ChnAreaIndex = [];
        ChnAreaNames = {};
        
        AlignedChnAreaIndex = [];
        AlignedChnAreaNames = {};
        AlignedTimes = 0;
        ShiftedValues = 0;
        
        AllenatlasPath = 'E:\MatCode\AllentemplateData'; % E:\AllenCCF % 'E:\MatCode\AllentemplateData'
        chanMap
        Colormap
        allPowerEst
        F
        
        % plot settings 
        commonYScales = [nan, nan];
        YValues = 10:10:3840; % to distinguish same depthed channels
        yTicks = 0:500:3840;
        AllAxYlims = {[0 3850],[0 3850],[0 3850],[0 3850],[0 3850]};
%         AllAxPos = {[];[];[];[];[]};
%         LastestYlims = [];
        
        Alignedhf = [];
        
        % status check variables
        IsAlignViewerButtonPressed = 0;
        IsAlignedAreaInFig = 0;
        ProbeTrajData % trajectory point data from anatomy results
    end
    
    methods (Access = private)
        
        function LoadDataANDplot(app)
            % function used to load data from selected path and plot all
            % the criterias
            if isempty(app.ksOutfilePath) || isempty(app.ksRawfilePath)
                warning('Session data path have not been defined.');
                return;
            end
            
            % reset some status variables
            app.AlignedTimes = 0;
            app.ShiftedValues = 0;
            app.IsAlignViewerButtonPressed = 0;
            app.IsAlignedAreaInFig = 0;
            app.lfpUsedClip = app.lfpDefaultClip;
            app.ProbeTrajData = [];
        
            % load lfp data
            LFPrawfilepathDir = dir(fullfile(app.ksRawfilePath,'*.lf.bin'));
            LFPrawfilepath = fullfile(app.ksRawfilePath,LFPrawfilepathDir(1).name);
            dataTypeNBytes = numel(typecast(cast(0, 'int16'), 'uint8'));
            
            app.NumSamples = LFPrawfilepathDir(1).bytes/(dataTypeNBytes*app.NumChn);
            lfpDataFull = memmapfile(LFPrawfilepath, 'Format', {'int16', [app.NumChn app.NumSamples], 'x'});
            app.lfpData = lfpDataFull.Data.x(:,1:(app.lfpDefaultTimeLen*app.lfpfs));
            
            plotClipData = app.lfpData(:,((app.lfpDefaultClip-1)*app.lfpfs+1):app.lfpDefaultClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            plotScales = [max(-30,min(plotClipData(:))), min(50,max(plotClipData(:)))];
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData,plotScales);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
%             app.LastestYlims = get(app.LFP_Ax,'ylim');
%             app.AllAxPos{3} = get(app.LFP_Ax,'position');
            fprintf('Plot LFP amplitude.\n');
            
            % load spike rate datas
            app.sp = loadKSdir(app.ksOutfilePath);
            [app.spTimes, app.spAmps, app.spDepths, app.spSites] = ...
                ksDriftmap(app.ksOutfilePath);
            
            depthBins = 0:20:3840;
            recordingDur = app.sp.st(end);
            [SpAmps, SpRates] = WFampsOverDepth(app.spAmps, app.spDepths, depthBins, recordingDur);
            DepthCenter = (depthBins(1:end-1)+depthBins(2:end))/2;
            
            
            % plot spike rate datas
            plot(app.SpikeRate_Ax,SpRates,DepthCenter);
            set(app.SpikeRate_Ax,'ylim',[0 3850]);
            xlabel(app.SpikeRate_Ax,'FireRate (Hz)');
            ylabel(app.SpikeRate_Ax,'Depth (um)');
%             app.AllAxPos{1} = get(app.SpikeRate_Ax,'position');
            set(app.SpikeRate_Ax,'YDir','Reverse');
            fprintf('Plot Spike rates.\n');
            
            % plot spike amplitude, 50% percentile values
            plot(app.Amplitude_Ax,SpAmps,DepthCenter);
            set(app.Amplitude_Ax,'ylim',[0 3850]);
            xlabel(app.Amplitude_Ax,'Amplitude (Unit)');
            ylabel(app.Amplitude_Ax,'Depth (um)');
            set(app.Amplitude_Ax,'YDir','Reverse');
%             app.AllAxPos{2} = get(app.Amplitude_Ax,'position');
            fprintf('Plot Spike amplitude.\n');
            
            %plot the LFP power, for different frequency bands
            if app.IsKsPathChanged || (isempty(app.allPowerEst) || isempty(app.chanMap))
                [~, app.allPowerEst, app.F, ~] = lfpBandPower(...
                    LFPrawfilepath,app.lfpfs,app.NumChn,[]);
                app.chanMap = readNPY(fullfile(app.ksOutfilePath, 'channel_map.npy'));
    %             nC = length(chanMap);
                app.allPowerEst = app.allPowerEst(:,app.chanMap+1)'; % now nChans x nFreq
            end
            
            freqBands = {[1.5 4], [4 10], [10 30], [30 80], [80 200]};
            NumFreqBands = length(freqBands);
            cla(app.LFPpower);
            hold(app.LFPpower,'on');
            lineColors = cool(NumFreqBands);
            for cfb = 1 : NumFreqBands
                inclF = app.F > freqBands{cfb}(1) & app.F <= freqBands{cfb}(2);
                cPower = mean(10*log10(app.allPowerEst(:,inclF)),2);
                plot(app.LFPpower,cPower, app.chanMap*10,'Color',lineColors(cfb,:));
                
            end
%             set(app.LFPpower,'ytick',[]);
            ylim(app.LFPpower,[0 3850]);
            xlabel(app.LFPpower,'Power (dB)');
            ylabel(app.LFPpower,'Depth (um)');
%             legend(app.LFPpower,cellfun(@(x) sprintf('%.1f - %.1f Hz',x(1),x(2)),freqBands,'UniformOutput',0));
%             app.AllAxPos{4} = get(app.LFPpower,'position');
            set(app.LFPpower,'YDir','Reverse');
            fprintf('Plot LFP band power.\n');
            
            % load channel areas and trajectory areas
            try
                ChnStrFilePath = fullfile(app.ksOutfilePath,'Chnlocation.mat');
                ChnposData = load(ChnStrFilePath,'ChnArea_Strings');
                app.ChnAreaIndex = cell2mat(ChnposData.ChnArea_Strings(2:end,2));
                app.ChnAreaNames = ChnposData.ChnArea_Strings(2:end,3);
            catch
                ChnStrFilePath = fullfile(app.ksOutfilePath,'..','allchannelav.mat');
                ChnposData = load(ChnStrFilePath,'allchannelav');
                app.ChnAreaIndex = cell2mat(ChnposData.allchannelav(2:end,1));
                app.ChnAreaNames = ChnposData.allchannelav(2:end,2);
            end
            
            
            ChnPosSteps = [0:20:3820;0:20:3820];
            ChnPosSteps = ChnPosSteps(:);
%             ChnPosSteps = app.sp.ycoords
            probeAreaPlotFun(app,app.ChannelArea_Ax,app.ChnAreaIndex,ChnPosSteps);
            title(app.ChannelArea_Ax,'Channel Areas');
            app.AllAxYlims{5} = get(app.ChannelArea_Ax,'ylim');
%             app.AllAxPos{5} = get(app.ChannelArea_Ax,'position');
            app.IsKsPathChanged = 0;
        end
        
        function varargout = probeAreaPlotFun(app,Ax,ChnAreas,ChnPos)
            cla(Ax);
            st = loadStructureTree([app.AllenatlasPath filesep 'structure_tree_safe_2017.csv']);
            
            ChnAreas(ChnAreas < 0) = 1;
            trajectory_area_boundary_idx = [1;find(diff(double(ChnAreas)) ~= 0)+1;384];
            trajectory_area_boundaries = ChnPos(trajectory_area_boundary_idx);
            trajectory_area_centers = (trajectory_area_boundaries(1:end-1) + diff(trajectory_area_boundaries)/2);
            trajectory_areaIndexes = ChnAreas(trajectory_area_boundary_idx);
            trajectory_area_labels = st.acronym(trajectory_areaIndexes);
            
            image(Ax,[],ChnPos,ChnAreas);
            hold(Ax,'on');
            colormap(Ax,app.Colormap.cmap);
            set(Ax,'yTick',trajectory_area_centers,'yticklabel',trajectory_area_labels,...
                'xtick',[],'YAxisLocation','right'); %'yticklabel',trajectory_area_labels,
            ylabel(Ax,[]);
            xlabel(Ax,[]);
            ylim(Ax,[0 3850]);
            for cBound = 1 : length(trajectory_area_boundaries)
                plot(Ax,[-10.5,1.5],trajectory_area_boundaries(cBound)*[1 1],'Color','r',...
                    'linewidth',1.0,'linestyle','--');
                if cBound > 1
                    if trajectory_areaIndexes(cBound) == 458 && cBound < length(trajectory_area_boundaries)
                        plot(Ax,[-10.5,1.5],trajectory_area_centers(cBound)*[1 1],'Color','k',...
                            'linewidth',1.4,'linestyle','--');
                    end
                    
                end
            end
            set(Ax,'Clipping','off');
            
            % for axis position alignments
            set(Ax,'xtick',1,'xlim',[0.5 1.5]);
            xlabel(Ax,'Areas');
            
            if nargout > 0
                ChnNames = st.safe_name(ChnAreas);
                varargout{1} = ChnNames;
            end
        end
        
        function CheckAlreadyAligned(app)
            % check whether the selected session is already aligned. If
            % already processed, choosed which type of the data will be
            % loaded in the channel area plots
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % check whether the Allen CCF path is valid
            if ~exist(app.AllenatlasPath,'dir')
                fprintf('Default allen ccf file folder non-exist. Please select new position.\n');
                CCFFolder = uigetdir(pwd,'Select AllenCCF folder');
                if ~ischar(CCFFolder) || ~exist(fullfile(CCFFolder,'template_volume_10um.npy'),'file')
                    warning('The selected folder do not have desired files, existing...');
                    return;
                else
                    app.AllenatlasPath = CCFFolder;
                end
                
            end
            allenCCF_path = fileparts(which('allenCCFbregma'));
            cmap_filename = [allenCCF_path filesep 'allen_ccf_colormap_2017.mat'];
            app.Colormap = load(cmap_filename);
            
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            folds = uigetdir(pwd);
            if ~ischar(folds)
                % no path is selected
                return;
            end
            app.ksRawfilePath = folds;
            app.ksOutfilePath = fullfile(folds, app.ksSortingType);
            
            LFPrawfilepathStrc = dir(fullfile(folds,'*.lf.bin'));
            if isempty(LFPrawfilepathStrc) || ~exist(fullfile(app.ksOutfilePath,'spike_times.npy'),'file')
                warning('The selected path do not have needed files for further analysis.');
                return;
            end
            set(app.SessPath,'Value',folds);
            app.IsKsPathChanged = 1;
            LoadDataANDplot(app);
        end

        % Value changed function: SessPath
        function SessPathValueChanged(app, event)
            folds = app.SessPath.Value;
            if ~ischar(folds)
                % no path is selected
                return;
            end
            app.ksRawfilePath = folds;
            app.ksOutfilePath = fullfile(folds, app.ksSortingType);
            
            LFPrawfilepathStrc = dir(fullfile(folds,'*.lf.bin'));
            if isempty(LFPrawfilepathStrc) || ~exist(fullfile(app.ksOutfilePath,'spike_times.npy'),'file')
                warning('The selected path do not have needed files for further analysis.');
                return;
            end
            app.IsKsPathChanged = 1;
            LoadDataANDplot(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if any(strcmpi(event.Modifier,'shift'))
                yChange = 5;
            else
                yChange = 50;
            end
            switch key
                % move the probe area upward
                case 'uparrow'
                    Newylim = get(app.ChannelArea_Ax,'ylim') - yChange;
                    ylim(app.ChannelArea_Ax,Newylim);
                    app.AllAxYlims{5} = Newylim;
                    app.IsAlignViewerButtonPressed = 0;
                case 'downarrow'
                    Newylim = get(app.ChannelArea_Ax,'ylim') + yChange;
                    ylim(app.ChannelArea_Ax,Newylim);
                    app.AllAxYlims{5} = Newylim;
                    app.IsAlignViewerButtonPressed = 0;
            end
                    
            
        end

        % Button pushed function: LFPRightClip
        function LFPRightClipButtonPushed(app, event)
            lfpDatapoints = size(app.lfpData,2);
            app.lfpUsedClip = app.lfpUsedClip + 1;
            if app.lfpUsedClip*app.lfpfs > lfpDatapoints
                app.lfpUsedClip = app.lfpUsedClip - 1;
                warning('Clip index out-of-range.');
                return;
            end
            plotClipData = app.lfpData(:,((app.lfpUsedClip-1)*app.lfpfs+1):app.lfpUsedClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            plotScales = [max(-30,min(plotClipData(:))), min(50,max(plotClipData(:)))];
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData,plotScales);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
%             app.LastestYlims = get(app.LFP_Ax,'ylim');
            fprintf('Plot LFP amplitude.\n');
        end

        % Button pushed function: LFPLeftClip
        function LFPLeftClipButtonPushed(app, event)
            
            app.lfpUsedClip = app.lfpUsedClip - 1;
            if app.lfpUsedClip < 1
                app.lfpUsedClip = app.lfpUsedClip + 1;
                warning('Clip index out-of-range.');
                return;
            end
            plotClipData = app.lfpData(:,((app.lfpUsedClip-1)*app.lfpfs+1):app.lfpUsedClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            plotScales = [max(-30,min(plotClipData(:))), min(50,max(plotClipData(:)))];
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData,plotScales);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
%             app.LastestYlims = get(app.LFP_Ax,'ylim');
            fprintf('Plot LFP amplitude.\n');
        end

        % Button pushed function: AlignmentpreviewerButton
        function AlignmentpreviewerButtonPushed(app, event)
            % load data from probeCCF data and recalculate the aligned
            % probe areas
            if isempty(app.ProbeTrajData)
                probeCCFFilePath = fullfile(app.ksOutfilePath,'ProbeCCFdata.mat');
                
                if ~exist(probeCCFFilePath,'file')
                    BackupPath = fullfile(app.ksOutfilePath,'..','probepoints.mat');
                    if ~exist(BackupPath,'file')
                        warning('Probe CCF data doesn''t exist.');
                        return;
                    else
                        probeCCFFilePath = BackupPath;
                    end
                end
                app.ProbeTrajData = load(probeCCFFilePath);
            end
            try
                cProbe_points = [app.ProbeTrajData.ProbeCCF.points;app.ProbeTrajData.ProbeCCF.endpoints];
            catch
                cProbe_points = [app.ProbeTrajData.probepoints.ROIPoints];
                [~, tip_index] = max(cProbe_points(:,2));
                EndPointCoord = cProbe_points(tip_index,:);
                cProbe_points(tip_index,:) = [];
                cProbe_points = [cProbe_points;EndPointCoord];
                cProbe_points = cProbe_points(:,[3,2,1]);
            end
            
            r0 = mean(cProbe_points,1);
            xyz = bsxfun(@minus,cProbe_points,r0);
            [U,S,V] = svd(xyz,0);
            histology_probe_direction = V(:,1);
            % (make sure the direction goes down in DV - flip if it's going up)
            if histology_probe_direction(2) < 0
                histology_probe_direction = -histology_probe_direction;
            end
            ProbDirectionAll = histology_probe_direction; 
            
            Online_probeDatapoints = U(:,1) * S(1,1) * (V(:,1))' + r0;
            probe_fit_endpoint = Online_probeDatapoints(end,:);
            
            ChnPosSteps = [20:20:3840;20:20:3840];
            ChnPosSteps = ChnPosSteps(:)/10; % convert to 10um pixel length
            
            if app.IsAlignedAreaInFig == 1 % aligned fig was plotted in fig at last time
                cShiftValues = app.ShiftedValues + app.AllAxYlims{5}(1);
                Chn2EndpointDis = ChnPosSteps + cShiftValues/10;
                app.IsAlignedAreaInFig = 0;
            else
                cShiftValues = app.ShiftedValues + app.AllAxYlims{5}(1);
                Chn2EndpointDis = ChnPosSteps + cShiftValues/10;
            end
            
%             tv = readNPY([app.AllenatlasPath filesep 'template_volume_10um.npy']);
            av = readNPY([app.AllenatlasPath filesep 'annotation_volume_10um_by_index.npy']);
%             st = loadStructureTree([app.AllenatlasPath filesep 'structure_tree_safe_2017.csv']);
            
            CorrectedChnCords = (round((-1)*Chn2EndpointDis * (ProbDirectionAll)'...
                + probe_fit_endpoint));
            % in case any channel position is out of index
            chan_coords_outofbounds = ...
                            any(CorrectedChnCords' < 1,1) | ...
                            any(CorrectedChnCords' > size(av)',1);
            %
            if sum(chan_coords_outofbounds) % if channal out of index position exists
                ChnOutboundInds = chan_coords_outofbounds;
                chn_coords_idx = sub2ind(size(av), ...
                    CorrectedChnCords(~ChnOutboundInds,1),CorrectedChnCords(~ChnOutboundInds,2),...
                    CorrectedChnCords(~ChnOutboundInds,3));
            else
                chn_coords_idx = sub2ind(size(av), ...
                    CorrectedChnCords(:,1),CorrectedChnCords(:,2),CorrectedChnCords(:,3));
                ChnOutboundInds = [];
            end
            chn_areas = int16(av(chn_coords_idx));
            if length(chn_areas) < 384
                chn_areas = [chn_areas;ones(384-length(chn_areas),1)];
            end
            app.AlignedChnAreaIndex = chn_areas;
            
            if isempty(app.Alignedhf) || ~ishandle(app.Alignedhf)
                app.Alignedhf = figure('position',[300 100 540 760]);
            else
                figure(app.Alignedhf);
                clf(app.Alignedhf);
            end
            RawAxLeft = subplot(121);
            probeAreaPlotFun(app,RawAxLeft,app.ChnAreaIndex,ChnPosSteps*10);
            title(RawAxLeft,'Before Alignment');
            set(RawAxLeft,'YAxisLocation','left');
            
            AlignedAxR = subplot(122);
            app.AlignedChnAreaNames = probeAreaPlotFun(app,AlignedAxR,chn_areas,ChnPosSteps*10);
%             title(AlignedAxR,'After Alignment');
            if cShiftValues > 0
                title(AlignedAxR,sprintf('After: Move upward %dum',round(cShiftValues)));
            else
                title(AlignedAxR,sprintf('After: Move downward %dum',round(abs(cShiftValues))));
            end
            app.IsAlignViewerButtonPressed = 1;
        end

        % Button pushed function: RePlotButton
        function RePlotButtonPushed(app, event)
            % replot all the axis
            LoadDataANDplot(app);
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            if isempty(app.AlignedChnAreaIndex)
                warning('No alignment data exists. Please exit current function if no correction is needed.');
                return;
            end
            if ~app.IsAlignViewerButtonPressed
                warndlg('Some alignments haven''t been considered. Click on the Alignment viewer button first.');
                return;
            end
            if isempty(app.AlignedChnAreaNames)
                st = loadStructureTree([app.AllenatlasPath filesep 'structure_tree_safe_2017.csv']);
                app.AlignedChnAreaNames = st.safe_name(app.AlignedChnAreaIndex);
            end
            AlignedAreaStrings = {app.AlignedChnAreaIndex,app.AlignedChnAreaNames};
            if app.AllAxYlims{5}(1) > 0
                app.ShiftedValues = app.ShiftedValues + app.AllAxYlims{5}(1);
            end
            AdjustedLen = app.ShiftedValues; % positive value indicates upward move, negtive indicates downward move
            
            saveFileName = fullfile(app.ksOutfilePath,'Chnlocation.mat');
            if exist(saveFileName,'file')
                BeforeAlignData = load(saveFileName);
                ChnArea_Strings = BeforeAlignData.ChnArea_Strings;
                ChnArea_indexes = BeforeAlignData.ChnArea_indexes;
                ChnArea_info = BeforeAlignData.ChnArea_info;
            
                save(saveFileName,'ChnArea_Strings','ChnArea_indexes','ChnArea_info',...
                    'AdjustedLen','AlignedAreaStrings','-v7.3');
            else
                ChnStrFilePath = fullfile(app.ksOutfilePath,'..','allchannelav.mat');
                ChnposData = load(ChnStrFilePath,'allchannelav');
                ChnArea_indexes = cell2mat(ChnposData.allchannelav(2:end,1));
                ChnArea_Strings = ChnposData.allchannelav(2:end,2);
                save(saveFileName,'ChnArea_Strings','ChnArea_indexes',...
                    'AdjustedLen','AlignedAreaStrings','-v7.3');
            end
            fprintf('Aligned data saved in file:\n %s\n',saveFileName);
        end

        % Button pushed function: PlotAlgnAreaInFig
        function PlotAlgnAreaInFigButtonPushed(app, event)
            % plot the aligned areas in the channel area axis for better
            % comparison of the alignment results
            if isempty(app.AlignedChnAreaIndex)
                warning('No alignment data exists. Please exit current function if no correction is needed.');
                return;
            end
            if ~app.IsAlignViewerButtonPressed
                warndlg('Some alignments haven''t been considered. Click on the Alignment viewer button first.');
                return;
            end
            app.ShiftedValues = app.ShiftedValues + app.AllAxYlims{5}(1);
            app.AlignedTimes = app.AlignedTimes + 1;
            ChnPosSteps = [20:20:3840;20:20:3840];
            ChnPosSteps = ChnPosSteps(:)/10; % convert to 10um pixel length
            probeAreaPlotFun(app,app.ChannelArea_Ax,app.AlignedChnAreaIndex,ChnPosSteps*10);
            if app.ShiftedValues >= 0
                title(app.ChannelArea_Ax,sprintf('Shift %d times, %dum upwards',...
                    app.AlignedTimes,app.ShiftedValues));
            else
                title(app.ChannelArea_Ax,sprintf('Shift %d times, %dum downwards',...
                    app.AlignedTimes,-app.ShiftedValues));
            end
            app.AllAxYlims{5} = get(app.ChannelArea_Ax,'ylim');
            app.IsAlignedAreaInFig = 1;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SpikeRate_Ax = uiaxes(app.UIFigure);
            title(app.SpikeRate_Ax, 'Spike Rate')
            xlabel(app.SpikeRate_Ax, 'X')
            ylabel(app.SpikeRate_Ax, 'Y')
            zlabel(app.SpikeRate_Ax, 'Z')
            app.SpikeRate_Ax.Tag = 'SpikeRate_Ax';
            app.Amplitude_Ax = uiaxes(app.UIFigure);
            title(app.Amplitude_Ax, 'Amplitude')
            xlabel(app.Amplitude_Ax, 'X')
            ylabel(app.Amplitude_Ax, 'Y')
            zlabel(app.Amplitude_Ax, 'Z')
            app.LFP_Ax = uiaxes(app.UIFigure);
            title(app.LFP_Ax, 'LFP Amplitude')
            xlabel(app.LFP_Ax, 'X')
            ylabel(app.LFP_Ax, 'Y')
            zlabel(app.LFP_Ax, 'Z')
            app.ChannelArea_Ax = uiaxes(app.UIFigure);
            title(app.ChannelArea_Ax, 'Channel Areas')
            xlabel(app.ChannelArea_Ax, 'X')
            ylabel(app.ChannelArea_Ax, 'Y')
            zlabel(app.ChannelArea_Ax, 'Z')
            app.LFPpower = uiaxes(app.UIFigure);
            title(app.LFPpower, 'LFP power')
            xlabel(app.LFPpower, 'X')
            ylabel(app.LFPpower, 'Y')
            zlabel(app.LFPpower, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SessPath = uieditfield(app.UIFigure, 'text');
            app.SessPath.ValueChangedFcn = createCallbackFcn(app, @SessPathValueChanged, true);
            app.SelectPathButton = uibutton(app.UIFigure, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.AlignmentpreviewerButton = uibutton(app.UIFigure, 'push');
            app.AlignmentpreviewerButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentpreviewerButtonPushed, true);
            app.AlignmentpreviewerButton.Text = 'Alignment previewer';
            app.SaveResultButton = uibutton(app.UIFigure, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.LFPLeftClip = uibutton(app.UIFigure, 'push');
            app.LFPLeftClip.ButtonPushedFcn = createCallbackFcn(app, @LFPLeftClipButtonPushed, true);
            app.LFPLeftClip.Text = '<';
            app.LFPRightClip = uibutton(app.UIFigure, 'push');
            app.LFPRightClip.ButtonPushedFcn = createCallbackFcn(app, @LFPRightClipButtonPushed, true);
            app.LFPRightClip.Text = '>';
            app.RePlotButton = uibutton(app.UIFigure, 'push');
            app.RePlotButton.ButtonPushedFcn = createCallbackFcn(app, @RePlotButtonPushed, true);
            app.RePlotButton.Text = 'RePlot';
            app.PlotAlgnAreaInFig = uibutton(app.UIFigure, 'push');
            app.PlotAlgnAreaInFig.ButtonPushedFcn = createCallbackFcn(app, @PlotAlgnAreaInFigButtonPushed, true);
            app.PlotAlgnAreaInFig.Text = 'Plot Aligned Area InFig';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Must click on this button to make the alignment take effects'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElecLandmarkAlignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end